classdef ActiveAvoidanceFordApp3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WayActiveAvoidanceFordLabPanel  matlab.ui.container.Panel
        AnimalIDEditFieldLabel          matlab.ui.control.Label
        AnimalIDEditField               matlab.ui.control.EditField
        TrialNumberEditFieldLabel       matlab.ui.control.Label
        TrialNumberEditField            matlab.ui.control.NumericEditField
        ITIMinsEditFieldLabel           matlab.ui.control.Label
        ITIMinsEditField                matlab.ui.control.NumericEditField
        ITIRangesEditFieldLabel         matlab.ui.control.Label
        ITIRangesEditField              matlab.ui.control.NumericEditField
        ShockDurationsEditFieldLabel    matlab.ui.control.Label
        ShockDurationsEditField         matlab.ui.control.NumericEditField
        TrialParametersLabel            matlab.ui.control.Label
        SelectSaveLocationButton        matlab.ui.control.Button
        GrabBackgroundButton            matlab.ui.control.Button
        TestHardwareButton              matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        SetArenasButton                 matlab.ui.control.Button
        CueDurationsEditFieldLabel      matlab.ui.control.Label
        CueDurationsEditField           matlab.ui.control.NumericEditField
        TrialSetupLabel                 matlab.ui.control.Label
        TrialControlLabel               matlab.ui.control.Label
        CameraSelectionDropDownLabel    matlab.ui.control.Label
        CameraSelectionDropDown         matlab.ui.control.DropDown
        RecordButton                    matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        X1Label                         matlab.ui.control.Label
        X2Label                         matlab.ui.control.Label
        Y1Label                         matlab.ui.control.Label
        Y2Label                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % This is where we are going to save globabl parameters
        AnimalID
        ITImin
        ITIrange
        ShockDur
        CueDur
        TrialDur
        cam
        SavePath
        r1 %Arena boundries
        r2 %Left Compartment
        r3 %Right Compartment
        r4 %blnk
        a %Arduino
        sensor %LED strip
        sensor2
        v %Video Recorder
        xrange % Left Arena x-cooridinates
        yrange % Left Arena y-coordinates
        Trial
        filename
        Latency
        Shocks
        Escapes
        Background
        Ready
        savecheck
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.Lamp.Color=[.6 .6 .6];
            app.CameraSelectionDropDown.Items=webcamlist;
            app.CameraSelectionDropDown.ItemsData=webcamlist;  
            app.AnimalID='AnimalA';
            assignin('base','AnimalID',app.AnimalID);
            assignin('base','camaraselection',1);
            app.xrange=[300, 640];
            app.yrange=[0, 480];
            clear cam
            clear a
            app.cam=webcam(1);
            preview(app.cam)
            app.Ready=0;
            app.savecheck=0;
            app.CueDur=10;
            app.TrialDur=30;
            app.ShockDur=2;
            app.ITImin=20;
            app.ITIrange=10;
            app.cam.ExposureMode='manual';
            app.cam.Exposure=-4;
            app.cam.Brightness=50;
        end

        % Value changed function: AnimalIDEditField
        function AnimalIDEditFieldValueChanged(app, event)
            value1 = app.AnimalIDEditField.Value;
            app.AnimalID=value1;
            assignin('base','AnimalID',app.AnimalID);
        end

        % Value changed function: TrialNumberEditField
        function TrialNumberEditFieldValueChanged(app, event)
            app.TrialDur = app.TrialNumberEditField.Value;
            assignin('base','TrialDur',app.TrialDur);
        end

        % Value changed function: ITIMinsEditField
        function ITIMinsEditFieldValueChanged(app, event)
            app.ITImin = app.ITIMinsEditField.Value;
            assignin('base','ITImin',app.ITImin);
        end

        % Value changed function: ITIRangesEditField
        function ITIRangesEditFieldValueChanged(app, event)
            app.ITIrange = app.ITIRangesEditField.Value;
            assignin('base','ITIrange',app.ITIrange);
        end

        % Value changed function: ShockDurationsEditField
        function ShockDurationsEditFieldValueChanged(app, event)
            app.ShockDur = app.ShockDurationsEditField.Value;
            assignin('base','ShockDur',app.ShockDur);
            
        end

        % Value changed function: CueDurationsEditField
        function CueDurationsEditFieldValueChanged(app, event)
            app.CueDur = app.CueDurationsEditField.Value;
            assignin('base','CueDur',app.CueDur);
        end

        % Value changed function: CameraSelectionDropDown
        function CameraSelectionDropDownValueChanged(app, event)
            value2 = app.CameraSelectionDropDown.Value;           
            index=webcamlist;
            cameraselection = find(strcmp(index, value2));
            assignin('base','camaraselection',cameraselection);
            closepreview;
            clear app.cam cam
            app.cam=webcam(cameraselection);
            preview(app.cam);
        end

        % Button pushed function: SelectSaveLocationButton
        function SelectSaveLocationButtonPushed(app, event)
            app.SavePath=uigetdir;
            assignin('base','SavePath',app.SavePath);
            app.savecheck=1;
        end

        % Button pushed function: GrabBackgroundButton
        function GrabBackgroundButtonPushed(app, event)
            frame2=snapshot(app.cam);
            figure
            imshow(frame2)
            title('Background')
            app.Background=medfilt2(rgb2gray(frame2));
            assignin('base','Background',frame2);
        end

        % Button pushed function: SetArenasButton
        function SetArenasButtonPushed(app, event)
            frame2=snapshot(app.cam);
            
            imtool(frame2)
%             title('Arena Selection')
%             app.r1=drawrectangle('Label','Arena','Color',[0 1 0]);
%             app.r2=drawrectangle('Label','Left Chamber','Color',[1 0 0]);
%             app.r3=drawrectangle('Label','RightChamber','Color',[0 0 1]);
%             app.r4=drawrectangle('Label','Blank','Color',[0 0 1]);
%             app.xrange=app.r2.Position(1:2:3);
%             app.yrange=app.r2.Position(2:2:4);
%             assignin('base','xrange',app.xrange)
%             assignin('base','yrange',app.yrange)
            
        end

        % Button pushed function: TestHardwareButton
        function TestHardwareButtonPushed(app, event)
            try
            app.a = arduino('COM3', 'Uno', 'Libraries', 'Adafruit/NeoPixel');
            %assignin('base','a',app.a)
            app.sensor = addon(app.a, 'Adafruit/NeoPixel', 'D10', 38);
            app.sensor2 = addon(app.a, 'Adafruit/NeoPixel', 'D11', 38);
            %assignin('base','sensor',app.sensor);
            app.sensor.Brightness=.05;
            app.sensor2.Brightness=.05;
            writeDigitalPin(app.a,'D8',1)
            writeDigitalPin(app.a,'D7',1)
            writeColor(app.sensor, 1:3:38, 'red');
            writeColor(app.sensor2, 1:3:38, 'red');
            pause(1);
            writeColor(app.sensor,1:3:38, 'blue');
            writeColor(app.sensor2,1:3:38, 'blue');
            pause(1);
            writeColor(app.sensor,1:3:38, 'green');
            writeColor(app.sensor2,1:3:38, 'green');
            pause(1)
            writeColor(app.sensor,'off');
            writeColor(app.sensor2,'off');
            writeDigitalPin(app.a,'D8',0);
            writeDigitalPin(app.a,'D7',0);
            app.Ready=1;
            catch
                display('Hardware Issue!');
            end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
%% This sets up the hardware if it hasn't already.
try
    if app.Ready==0
        app.a = arduino('COM3', 'Uno', 'Libraries', 'Adafruit/NeoPixel');
        %assignin('base','a',app.a)
        app.sensor = addon(app.a, 'Adafruit/NeoPixel', 'D10', 38);
        app.sensor2 = addon(app.a, 'Adafruit/NeoPixel', 'D11', 38);
        app.sensor.Brightness=.1;
        app.sensor2.Brightness=.1;
        %assignin('base','sensor',app.sensor);
    else
        display('Hardware Ready')
    end
catch
    display('Hardware Error');
end

Threshold=10; %Detection threshold
MinSize=400; %Minimum mouse size.
MaxSize=20000; %Maximim mouse size.
app.filename=strcat(app.AnimalID,strrep(datestr(now),':','-')); %
app.Trial=1; %First trial is 1
TrialState=0; %Trial starts out in the ISI preiod
app.Escapes=zeros(app.TrialDur,1); %Array for escapes.
app.Shocks=zeros(app.TrialDur,1); %Array for shocks
app.Latency=zeros(app.TrialDur,1); %Array for escape latency

if app.savecheck==1
    mkdir(app.SavePath,app.filename)
    try
        imwrite(app.Background,strcat(app.SavePath,'\',app.filename,'\','Background',app.filename,'.png'));
    end
    cd(fullfile(app.SavePath,app.filename));
else
    name=pwd;
    mkdir(pwd,app.filename)
    imwrite(app.Background,strcat(name,'\',app.filename,'\','Background',app.filename,'.png'));
    cd(fullfile(name,app.filename));
end
app.v=VideoWriter(app.filename);%Creates A video
closepreview;
pause(1)
preview(app.cam);
app.Lamp.Color='green';
open(app.v)

while app.Trial <= app.TrialDur
    if TrialState==0
        ITI = ceil(rand*app.ITIrange) + app.ITImin; %Random time interval for pause
        tic; %Start counting the ITI duration
        t=0;
        while t<=ITI
            t=toc; %Check the current ITI time.
            try
                img=snapshot(app.cam); %Grab a pic.
                writeVideo(app.v,img); %Write that pic to video.
            catch
                disp('failed frame') %If you fail to grab a pic, just let me know.
            end
        end
        TrialState=1; %Once the ITI is over, write the TrialState to 1.
    end
    
    if TrialState==1
        shock=1;
        
        mousecheck = medfilt2(rgb2gray(img));
        ImageDifference = logical((app.Background-mousecheck)>Threshold);
        ImageDifference = imfill(ImageDifference, 'holes');
        ImageDifference = bwareafilt(ImageDifference, [MinSize,MaxSize]);
        ImageDifference = bwareafilt(ImageDifference, 1);
        Centerpoint = regionprops(ImageDifference,'Centroid');
        in1=inpolygon(Centerpoint.Centroid(1),Centerpoint.Centroid(2),app.xrange,app.yrange);
        
        if in1==0
            writeColor(app.sensor,1:3:38, 'green');
        else
            writeColor(app.sensor2,1:3:38, 'green');
        end
        tic;
        cuetime=0;
        while cuetime <= app.CueDur
            try
                img=snapshot(app.cam); %Grab a pic.
                writeVideo(app.v,img); %Write that pic to video.
                mousecheck = medfilt2(rgb2gray(img));
                ImageDifference = logical((app.Background-mousecheck)>Threshold);
                ImageDifference = imfill(ImageDifference, 'holes');
                ImageDifference = bwareafilt(ImageDifference, [MinSize,MaxSize]);
                ImageDifference = bwareafilt(ImageDifference, 1);
                Centerpoint1 = regionprops(ImageDifference,'Centroid');
                in2 = inpolygon(Centerpoint1.Centroid(1),Centerpoint1.Centroid(2),app.xrange,app.yrange);     
            catch
                in2=in1;
                %display('Target Lost');
            end
            cuetime=toc;
            if in1 == in2
                shock = 1;
            else
                shock=0;
                break
            end
        end
        
        if shock==1
            
            if in1==0
                writeDigitalPin(app.a,'D7',1);
            else
                writeDigitalPin(app.a,'D8',1);
            end
            tic;
            s=0;
            app.Shocks(app.Trial)=1;
            while s<=app.ShockDur
                img=snapshot(app.cam); %Grab a pic.
                writeVideo(app.v,img); %Write that pic to video.
                s=toc;
            end
            writeDigitalPin(app.a,'D8',0);
            writeDigitalPin(app.a,'D7',0);
            app.Shocks(app.Trial)=1;
        end
        writeColor(app.sensor,'off');
        writeColor(app.sensor2,'off');
        if shock==0
%             app.sensor2.Brightness=.005;
%             app.sensor.Brightness=.005;
%            if in1==0
%                 for k=1:13
%                     i=k*3;
%                     q=39-i;
%                     writeColor(app.sensor2,i-2:i, 'green')
%                     writeColor(app.sensor2,q+1:q+2,'green')
%                     writeColor(app.sensor2,'off')
%                     img=snapshot(app.cam); %Grab a pic.
%                     writeVideo(app.v,img);
%                 end
%            else
%                 for k=1:13
%                     i=k*3;
%                     q=39-i;
%                     writeColor(app.sensor,i-2:i, 'green')
%                     writeColor(app.sensor,q+1:q+2,'green')
%                     writeColor(app.sensor,'off');
%                     img=snapshot(app.cam); %Grab a pic.
%                     writeVideo(app.v,img);
%                 end
                
%            end
            app.Escapes(app.Trial)=1;
            app.Latency(app.Trial)=cuetime;
        end
        writeColor(app.sensor,'off');
        writeColor(app.sensor2,'off');
%        app.sensor2.Brightness=.1;
%        app.sensor.Brightness=.1;
        TrialState=0;
        app.Trial=app.Trial+1;
        display(app.Trial);
    end
end

close(app.v);
close all
app.Lamp.Color=[.6 .6 .6];

Latency=app.Latency;
Escapes=app.Escapes;
Shocks=app.Shocks;
TrialDur=app.TrialDur;
ShockDur=app.ShockDur;
CueDur=app.CueDur;
ITImin=app.ITImin;
ITIrange=app.ITIrange;
AnimalID=app.AnimalID;

save(app.filename,'Latency','Escapes','Shocks','TrialDur','ShockDur','ITImin','ITIrange','CueDur','AnimalID')

closepreview
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                app.Trial=app.TrialDur+1;
                close(app.v);
                Latency=app.Latency;
                Escapes=app.Escapes;
                Shocks=app.Shocks;
                TrialDur=app.TrialDur;
                ShockDur=app.ShockDur;
                CueDur=app.CueDur;
                ITImin=app.ITImin;
                ITIrange=app.ITIrange;
                AnimalID=app.AnimalID;
                save(app.filename,'Latency','Escapes','Shocks','TrialDur','ShockDur','ITImin','ITIrange','CueDur','AnimalID')
                close all
            end
            closereq();
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.xrange(1) = app.EditField.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.xrange(2) = app.EditField_2.Value;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.yrange(1)= app.EditField_3.Value;
           
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.yrange(2) = app.EditField_4.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WayActiveAvoidanceFordLabPanel = uipanel(app.UIFigure);
            app.WayActiveAvoidanceFordLabPanel.Title = '2-Way Active Avoidance Ford Lab';
            app.AnimalIDEditFieldLabel = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.AnimalIDEditFieldLabel.Text = 'Animal ID';
            app.AnimalIDEditField = uieditfield(app.WayActiveAvoidanceFordLabPanel, 'text');
            app.AnimalIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnimalIDEditFieldValueChanged, true);
            app.AnimalIDEditField.Value = 'AnimalA';
            app.TrialNumberEditFieldLabel = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.TrialNumberEditFieldLabel.Text = 'Trial Number';
            app.TrialNumberEditField = uieditfield(app.WayActiveAvoidanceFordLabPanel, 'numeric');
            app.TrialNumberEditField.ValueChangedFcn = createCallbackFcn(app, @TrialNumberEditFieldValueChanged, true);
            app.TrialNumberEditField.Value = 30;
            app.ITIMinsEditFieldLabel = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.ITIMinsEditFieldLabel.Text = 'ITI Min (s)';
            app.ITIMinsEditField = uieditfield(app.WayActiveAvoidanceFordLabPanel, 'numeric');
            app.ITIMinsEditField.ValueChangedFcn = createCallbackFcn(app, @ITIMinsEditFieldValueChanged, true);
            app.ITIMinsEditField.Value = 20;
            app.ITIRangesEditFieldLabel = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.ITIRangesEditFieldLabel.Text = 'ITI Range (s)';
            app.ITIRangesEditField = uieditfield(app.WayActiveAvoidanceFordLabPanel, 'numeric');
            app.ITIRangesEditField.ValueChangedFcn = createCallbackFcn(app, @ITIRangesEditFieldValueChanged, true);
            app.ITIRangesEditField.Value = 10;
            app.ShockDurationsEditFieldLabel = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.ShockDurationsEditFieldLabel.Text = 'ShockDuration (s)';
            app.ShockDurationsEditField = uieditfield(app.WayActiveAvoidanceFordLabPanel, 'numeric');
            app.ShockDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @ShockDurationsEditFieldValueChanged, true);
            app.ShockDurationsEditField.Value = 2;
            app.TrialParametersLabel = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.TrialParametersLabel.Text = 'Trial Parameters';
            app.SelectSaveLocationButton = uibutton(app.WayActiveAvoidanceFordLabPanel, 'push');
            app.SelectSaveLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSaveLocationButtonPushed, true);
            app.SelectSaveLocationButton.Text = 'Select Save Location';
            app.GrabBackgroundButton = uibutton(app.WayActiveAvoidanceFordLabPanel, 'push');
            app.GrabBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @GrabBackgroundButtonPushed, true);
            app.GrabBackgroundButton.Text = 'Grab Background';
            app.TestHardwareButton = uibutton(app.WayActiveAvoidanceFordLabPanel, 'push');
            app.TestHardwareButton.ButtonPushedFcn = createCallbackFcn(app, @TestHardwareButtonPushed, true);
            app.TestHardwareButton.Text = 'Test Hardware';
            app.Lamp = uilamp(app.WayActiveAvoidanceFordLabPanel);
            app.SetArenasButton = uibutton(app.WayActiveAvoidanceFordLabPanel, 'push');
            app.SetArenasButton.ButtonPushedFcn = createCallbackFcn(app, @SetArenasButtonPushed, true);
            app.SetArenasButton.Text = 'Set Arenas';
            app.CueDurationsEditFieldLabel = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.CueDurationsEditFieldLabel.Text = 'Cue Duration (s)';
            app.CueDurationsEditField = uieditfield(app.WayActiveAvoidanceFordLabPanel, 'numeric');
            app.CueDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @CueDurationsEditFieldValueChanged, true);
            app.CueDurationsEditField.Value = 10;
            app.TrialSetupLabel = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.TrialSetupLabel.Text = 'Trial Setup';
            app.TrialControlLabel = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.TrialControlLabel.Text = 'Trial Control';
            app.CameraSelectionDropDownLabel = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.CameraSelectionDropDownLabel.Text = 'Camera Selection';
            app.CameraSelectionDropDown = uidropdown(app.WayActiveAvoidanceFordLabPanel);
            app.CameraSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraSelectionDropDownValueChanged, true);
            app.RecordButton = uibutton(app.WayActiveAvoidanceFordLabPanel, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.CloseButton = uibutton(app.WayActiveAvoidanceFordLabPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.EditField = uieditfield(app.WayActiveAvoidanceFordLabPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 300;
            app.EditField_2 = uieditfield(app.WayActiveAvoidanceFordLabPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 640;
            app.EditField_3 = uieditfield(app.WayActiveAvoidanceFordLabPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.WayActiveAvoidanceFordLabPanel, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 480;
            app.X1Label = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.X1Label.Text = 'X1';
            app.X2Label = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.X2Label.Text = 'X2';
            app.Y1Label = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.Y1Label.Text = 'Y1';
            app.Y2Label = uilabel(app.WayActiveAvoidanceFordLabPanel);
            app.Y2Label.Text = 'Y2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ActiveAvoidanceFordApp3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end